classdef GUI_Test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Panel_Beam                matlab.ui.container.Panel
        GridLayout8_2             matlab.ui.container.GridLayout
        KnotenPanel               matlab.ui.container.Panel
        GridLayout10              matlab.ui.container.GridLayout
        KnotenTable               matlab.ui.control.Table
        FederPanel                matlab.ui.container.Panel
        GridLayout13              matlab.ui.container.GridLayout
        FederTable                matlab.ui.control.Table
        LagerPanel                matlab.ui.container.Panel
        GridLayout12              matlab.ui.container.GridLayout
        LagerTable                matlab.ui.control.Table
        StbePanel                 matlab.ui.container.Panel
        GridLayout11              matlab.ui.container.GridLayout
        StaebeTable               matlab.ui.control.Table
        Panel_PointLoads          matlab.ui.container.Panel
        GridLayout9               matlab.ui.container.GridLayout
        StabLastKonzTable         matlab.ui.control.Table
        KnotenLastTable           matlab.ui.control.Table
        Panel_Moments             matlab.ui.container.Panel
        GridLayout4               matlab.ui.container.GridLayout
        EinflusslinieTable        matlab.ui.control.Table
        BackButton_7              matlab.ui.control.Button
        Panel_DistrLoads          matlab.ui.container.Panel
        GridLayout3               matlab.ui.container.GridLayout
        StabLastVertTable         matlab.ui.control.Table
        BackButton_8              matlab.ui.control.Button
        Panel_ForcedDisp          matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        ZwTable                   matlab.ui.control.Table
        BackButton_6              matlab.ui.control.Button
        Panel_Section             matlab.ui.container.Panel
        GridLayout5               matlab.ui.container.GridLayout
        QuerschnitteTable         matlab.ui.control.Table
        GridLayout                matlab.ui.container.GridLayout
        HostPanel                 matlab.ui.container.Panel
        GridLayout14              matlab.ui.container.GridLayout
        Button_ClosePanel         matlab.ui.control.Button
        GewnschterOutputDropDown  matlab.ui.control.DropDown
        GewnschterOutputLabel     matlab.ui.control.Label
        EinflsseLabel             matlab.ui.control.Label
        Button_Material           matlab.ui.control.Button
        Button_Berechnen          matlab.ui.control.Button
        Button_VertLasten         matlab.ui.control.Button
        Button_Zwaengungen        matlab.ui.control.Button
        Button_Einflusslinie      matlab.ui.control.Button
        Button_PunktLasten        matlab.ui.control.Button
        Button_Querschnitt        matlab.ui.control.Button
        Button_Staebe             matlab.ui.control.Button
        SystemViewer              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Panels struct % maps keys -> panel handles
    end
    
    methods (Access = private)
        
        function in = guiToInput(app)
        % Beam geometry
        in.Knoten = app.KnotenTable.Data;
        in.Staebe = app.StaebeTable.Data;

        % Cross-sections
        in.Querschnitte = app.QuerschnitteTable.Data;

        % Springs
        in.Feder = app.FederTable.Data;

        % Supports
        in.Lager = app.buildLager(); 

        % Prescribed displacements
        in.VorgeschriebeneVerschiebung = app.ZwTable.Data;

        % Loads
        in.KnotenLasten            = app.KnotenLastTable.Data;
        in.StabLasten_konzentriert = app.StabLastKonzTable.Data;
        in.StabLasten_verteilt     = app.StabLastVertTable.Data;

        % Optional: Teilsysteme / Kondensation
        in.Kondensation = [];  % you can add later

        % OUTPUT SELECTION / INFLUENCE LINE 
        val = string(app.GewnschterOutputDropDown.Value);
        switch val
            case "Schnittkräfte",       in.gew_output = 1;
            case "Einflusslinie",       in.gew_output = 2;
            case "Auflagerreaktionen",  in.gew_output = 3;
            otherwise                   in.gew_output = 1;  % default
        end

        in.Einflusslinie = app.EinflusslinieTable.Data;

        end

        function Lager = buildLager(app)
                T = app.LagerTable.Data;

                Knoten   = T.Knoten;       % numeric column
                Lagerung = double(T.Var2); % Var2 is categorical -> 1..6

                Lager = table(Knoten, Lagerung);
        end

        function panelsToParent(app)
            app.Panels = struct( ...
                'Beam',         app.Panel_Beam, ...
                'Section',      app.Panel_Section, ...
                'PointLoads',   app.Panel_PointLoads, ...
                'Moments',      app.Panel_Moments, ...
                'DistrLoads',    app.Panel_DistrLoads, ...
                'ForcedDisp',   app.Panel_ForcedDisp );

            % Make every card fill its parent and start hidden
            panels = struct2cell(app.Panels);
            for k = 1:numel(panels)
                p = panels{k};
                p.Parent   = app.HostPanel;        % or app.CardHostPanel if that's your host
                p.Units    = 'normalized';
                p.Position = [0 0 1 1];
                p.Visible  = 'off';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        
        panelsToParent(app);


        % ---------- INITIALIZE ALL TABLES WITH EMPTY NUMERIC COLUMNS ----------
        % (ensures users can click & start typing directly)
        % app.KnotenTable.Data        = table(NaN, NaN, 'VariableNames', {'xPos','yPos'});
        % app.StaebeTable.Data        = table(NaN, NaN, NaN, 'VariableNames', {'StartKnoten','EndKnoten','Querschnitt'});
        % app.QuerschnitteTable.Data  = table(NaN, NaN, NaN, NaN, NaN, ...
        %     'VariableNames', {'EModul','Flaeche','Traegheitsmoment','GelenkStabAnfang','GelenkStabende'});
        % app.KnotenLastTable.Data    = table(NaN, NaN, NaN, 'VariableNames', {'Knoten','Richtung','Wert'});
        % app.StabLastKonzTable.Data  = table(NaN, NaN, NaN, NaN, 'VariableNames', {'Stab','Richtung','Wert','StartPosition'});
        % app.StabLastVertTable.Data  = table(NaN, NaN, NaN, NaN, NaN, ...
        %     'VariableNames', {'Stab','Richtung','Wert','StartPosition','EndPosition'});
        % app.ZwTable.Data            = table(NaN, NaN, NaN, 'VariableNames', {'Knoten','Richtung','Wert'});
        % app.FederTable.Data         = table(NaN, NaN, NaN, 'VariableNames', {'Knoten','Feder','Betrag'});
         app.EinflusslinieTable.Data = table(4, 1, 3, 'VariableNames', {'TypEL','Knoten','Richtung'});
         %Knoten
         xPos = [0,10,20]';
         yPos = [0,5,0]';
         app.KnotenTable.Data = table(xPos, yPos);
         %Stäbe
         StartKnoten = [1,2]';
         EndKnoten   = [2,3]';
         Querschnitt = [1,2]';
         app.StaebeTable.Data = table(StartKnoten, EndKnoten, Querschnitt);

         %Querschnitte ( <-- irgendwelche Zahlen)
         %nQuerschnitte = size(Querschnitt,1);
         EModul = [200,200]';
         nQuerschnitte = size(EModul,1);
         Flaeche = [0.2,0.2*2.5]';
         Traegheitsmoment = [0.16,0.16]';
         GelenkStabAnfang = [3,3]';
         GelenkStabende   = [3,3]';
         dehnstarr = false(nQuerschnitte,1);
         biegesteif = false(nQuerschnitte,1);
         app.QuerschnitteTable.Data = table(EModul, Flaeche, Traegheitsmoment, GelenkStabAnfang, GelenkStabende, dehnstarr, biegesteif);
         %Federn
         Knoten = []';
         Feder  = []';
         Betrag = []';
         app.FederTable.Data = table(Knoten, Feder, Betrag);

         %Lager
         Knoten = [1,3]';

         LagerArten = { ...
             'Eingespannt', ...             % 1
             'Gelenkig', ...                % 2
             'Rolllager y-Richtung', ...    % 3
             'Rolllager x-Richtung', ...    % 4
             'Gleitlager y-Richtung', ...   % 5
             'Gleitlager x-Richtung'};      % 6
         
         %LagerData = {'Choose'; 'Choose'};
         app.LagerTable.Data = table(Knoten, categorical({'Eingespannt';'Eingespannt'}, LagerArten));
         %Zwängungen
         Knoten = [];
         Richtung = [];
         Wert = [];
         app.ZwTable.Data = table(Knoten, Richtung, Wert);
         %KnotenLasten
         Knoten   = [2,2]';
         Richtung = [1,2]';
         Wert     = [100,300]';
         app.KnotenLastTable.Data = table(Knoten, Richtung, Wert);
         %StabLasten (konzentriert)
         Stab = []';
         Richtung = []';
         Wert = []';
         StartPosition = []';
         app.StabLastKonzTable.Data = table(Stab, Richtung, Wert, StartPosition);
         %StabLasten (verteilt)
         Stab = [];
         Richtung = [];
         Wert = [];
         StartPosition = [];
         EndPosition   = [];
         app.StabLastVertTable.Data = table(Stab, Richtung, Wert, StartPosition, EndPosition);
         %gewünschter Output
         % in.gew_output = 1; % 1:Schnittkräfte; 2:Einflusslinie
        end

        % Button pushed function: Button_Einflusslinie, 
        % ...and 5 other components
        function LeftMenuButtonPushed(app, event)
        % get tag of the pressed button
        key = string(event.Source.Tag); 
        
        % resize system viewer
        app.SystemViewer.Layout.Column = [3 4];

        % show the matching one
        set(app.HostPanel, 'Visible', 'on');
        set(app.Panels.(key), 'Visible', 'on');
        uistack(app.Panels.(key), 'top');

        % overlay the close Button
        app.Button_ClosePanel.Parent = app.Panels.(key);
        uistack(app.Button_ClosePanel, 'top');
        end

        % Button pushed function: BackButton_6, BackButton_7, 
        % ...and 2 other components
        function BackButtonPushed(app, event)

            % resize system viewer
            app.SystemViewer.Layout.Column = [2 4];
            
            % remove panel
            event.Source.Parent.Parent.Visible = 'off';
            app.HostPanel.Visible = 'off';
        end

        % Button pushed function: Button_Berechnen
        function Button_BerechnenPushed(app, event)
            try
                in = guiToInput(app);
                %out = inputUmwandeln(in);
                [Model, status, Meldung, issues] = MatrizenStatik(in);
                disp('Run completed successfully');
            catch ME
                uialert(app.UIFigure, ME.message, 'Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SystemViewer = uiaxes(app.GridLayout);
            title(app.SystemViewer, 'System Vorschau')
            xlabel(app.SystemViewer, 'X')
            ylabel(app.SystemViewer, 'Y')
            zlabel(app.SystemViewer, 'Z')
            app.SystemViewer.SubtitleFontWeight = 'bold';
            app.Button_Staebe = uibutton(app.GridLayout, 'push');
            app.Button_Staebe.ButtonPushedFcn = createCallbackFcn(app, @LeftMenuButtonPushed, true);
            app.Button_Staebe.Tag = 'Beam';
            app.Button_Staebe.Text = 'Stäbe';
            app.Button_Querschnitt = uibutton(app.GridLayout, 'push');
            app.Button_Querschnitt.ButtonPushedFcn = createCallbackFcn(app, @LeftMenuButtonPushed, true);
            app.Button_Querschnitt.Tag = 'Section';
            app.Button_Querschnitt.Text = 'Querschnitt';
            app.Button_PunktLasten = uibutton(app.GridLayout, 'push');
            app.Button_PunktLasten.ButtonPushedFcn = createCallbackFcn(app, @LeftMenuButtonPushed, true);
            app.Button_PunktLasten.Tag = 'PointLoads';
            app.Button_PunktLasten.Text = 'Punktlasten';
            app.Button_Einflusslinie = uibutton(app.GridLayout, 'push');
            app.Button_Einflusslinie.ButtonPushedFcn = createCallbackFcn(app, @LeftMenuButtonPushed, true);
            app.Button_Einflusslinie.Tag = 'Moments';
            app.Button_Einflusslinie.Text = 'Einflusslinie';
            app.Button_Zwaengungen = uibutton(app.GridLayout, 'push');
            app.Button_Zwaengungen.ButtonPushedFcn = createCallbackFcn(app, @LeftMenuButtonPushed, true);
            app.Button_Zwaengungen.Tag = 'ForcedDisp';
            app.Button_Zwaengungen.Text = 'Zwängungen';
            app.Button_VertLasten = uibutton(app.GridLayout, 'push');
            app.Button_VertLasten.ButtonPushedFcn = createCallbackFcn(app, @LeftMenuButtonPushed, true);
            app.Button_VertLasten.Tag = 'DistrLoads';
            app.Button_VertLasten.Text = 'Verteilte Lasten';
            app.Button_Berechnen = uibutton(app.GridLayout, 'push');
            app.Button_Berechnen.ButtonPushedFcn = createCallbackFcn(app, @Button_BerechnenPushed, true);
            app.Button_Berechnen.Text = 'Berechnen';
            app.Button_Material = uibutton(app.GridLayout, 'push');
            app.Button_Material.Text = 'Material';
            app.EinflsseLabel = uilabel(app.GridLayout);
            app.EinflsseLabel.Tag = 'LeftPanel';
            app.EinflsseLabel.Text = 'Einflüsse';
            app.GewnschterOutputLabel = uilabel(app.GridLayout);
            app.GewnschterOutputLabel.Text = 'Gewünschter Output:';
            app.GewnschterOutputDropDown = uidropdown(app.GridLayout);
            app.GewnschterOutputDropDown.Items = {'Schnittkräfte', 'Einflusslinie', 'Auflagerreaktionen'};
            app.GewnschterOutputDropDown.Value = 'Schnittkräfte';
            app.HostPanel = uipanel(app.GridLayout);
            app.HostPanel.Visible = 'off';
            app.HostPanel.Tag = 'LeftPanel';
            app.GridLayout14 = uigridlayout(app.HostPanel);
            app.Button_ClosePanel = uibutton(app.GridLayout14, 'push');
            app.Button_ClosePanel.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.Button_ClosePanel.Icon = fullfile(pathToMLAPP, 'icons', 'icon-fa-window-close.png');
            app.Button_ClosePanel.Text = '';
            app.Panel_Section = uipanel(app.UIFigure);
            app.GridLayout5 = uigridlayout(app.Panel_Section);
            app.QuerschnitteTable = uitable(app.GridLayout5);
            app.QuerschnitteTable.ColumnName = {'EModul'; 'Flaeche'; 'Traegheitsmoment'; 'GelenkStabAnfang'; 'GelenkStabende'; 'dehnstarr'; 'biegesteif'};
            app.QuerschnitteTable.RowName = {};
            app.QuerschnitteTable.ColumnEditable = [true true true true true];
            app.Panel_ForcedDisp = uipanel(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.Panel_ForcedDisp);
            app.BackButton_6 = uibutton(app.GridLayout2, 'push');
            app.BackButton_6.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton_6.Text = 'Back';
            app.ZwTable = uitable(app.GridLayout2);
            app.ZwTable.ColumnName = {'Knoten'; 'Richtung'; 'Wert'};
            app.ZwTable.RowName = {};
            app.ZwTable.ColumnEditable = [true true true];
            app.Panel_DistrLoads = uipanel(app.UIFigure);
            app.GridLayout3 = uigridlayout(app.Panel_DistrLoads);
            app.BackButton_8 = uibutton(app.GridLayout3, 'push');
            app.BackButton_8.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton_8.Text = 'Back';
            app.StabLastVertTable = uitable(app.GridLayout3);
            app.StabLastVertTable.ColumnName = {'Stab'; 'Richtung'; 'Wert'; 'StartPosition'; 'EndPosition'};
            app.StabLastVertTable.RowName = {};
            app.StabLastVertTable.ColumnEditable = [true true true true true];
            app.Panel_Moments = uipanel(app.UIFigure);
            app.GridLayout4 = uigridlayout(app.Panel_Moments);
            app.BackButton_7 = uibutton(app.GridLayout4, 'push');
            app.BackButton_7.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton_7.Text = 'Back';
            app.EinflusslinieTable = uitable(app.GridLayout4);
            app.EinflusslinieTable.ColumnName = {'TypEL'; 'Knoten'; 'Richtung'};
            app.EinflusslinieTable.RowName = {};
            app.EinflusslinieTable.ColumnEditable = [true true true];
            app.Panel_PointLoads = uipanel(app.UIFigure);
            app.GridLayout9 = uigridlayout(app.Panel_PointLoads);
            app.KnotenLastTable = uitable(app.GridLayout9);
            app.KnotenLastTable.ColumnName = {'Knoten'; 'Richtung'; 'Wert'};
            app.KnotenLastTable.RowName = {};
            app.KnotenLastTable.ColumnEditable = [true true true];
            app.StabLastKonzTable = uitable(app.GridLayout9);
            app.StabLastKonzTable.ColumnName = {'Stab'; 'Richtung'; 'Wert'; 'StartPosition'};
            app.StabLastKonzTable.RowName = {};
            app.StabLastKonzTable.ColumnEditable = [true true true true];
            app.Panel_Beam = uipanel(app.UIFigure);
            app.GridLayout8_2 = uigridlayout(app.Panel_Beam);
            app.StbePanel = uipanel(app.GridLayout8_2);
            app.StbePanel.Title = 'Stäbe';
            app.GridLayout11 = uigridlayout(app.StbePanel);
            app.StaebeTable = uitable(app.GridLayout11);
            app.StaebeTable.ColumnName = {'StartKnoten'; 'EndKnoten'; 'Querschnitt'};
            app.StaebeTable.RowName = {'1'; '2'; '3'; '4'};
            app.StaebeTable.ColumnEditable = [true true true];
            app.LagerPanel = uipanel(app.GridLayout8_2);
            app.LagerPanel.Title = 'Lager';
            app.GridLayout12 = uigridlayout(app.LagerPanel);
            app.LagerTable = uitable(app.GridLayout12);
            app.LagerTable.ColumnName = {'Knoten'; 'Lager'};
            app.LagerTable.RowName = {'1'; '2'; '3'; '4'};
            app.LagerTable.ColumnEditable = [true true];
            app.FederPanel = uipanel(app.GridLayout8_2);
            app.FederPanel.Title = 'Feder';
            app.GridLayout13 = uigridlayout(app.FederPanel);
            app.FederTable = uitable(app.GridLayout13);
            app.FederTable.ColumnName = {'Knoten'; 'Feder'; 'Betrag'};
            app.FederTable.RowName = {};
            app.FederTable.ColumnEditable = [true true true];
            app.KnotenPanel = uipanel(app.GridLayout8_2);
            app.KnotenPanel.Title = 'Knoten';
            app.GridLayout10 = uigridlayout(app.KnotenPanel);
            app.KnotenTable = uitable(app.GridLayout10);
            app.KnotenTable.ColumnName = {'xPos'; 'yPos'};
            app.KnotenTable.RowName = {'1'; '2'; '3'; '4'};
            app.KnotenTable.ColumnEditable = [true true];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end